classdef kostky_ruta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        EndStatusEditField       matlab.ui.control.EditField
        EndStatusEditFieldLabel  matlab.ui.control.Label
        Dice5EditField           matlab.ui.control.NumericEditField
        Dice5EditFieldLabel      matlab.ui.control.Label
        Dice4EditField           matlab.ui.control.NumericEditField
        Dice4EditFieldLabel      matlab.ui.control.Label
        Dice3EditField           matlab.ui.control.NumericEditField
        Dice3EditFieldLabel      matlab.ui.control.Label
        Dice2EditField           matlab.ui.control.NumericEditField
        Dice2EditFieldLabel      matlab.ui.control.Label
        Dice1EditField           matlab.ui.control.NumericEditField
        Dice1EditFieldLabel      matlab.ui.control.Label
        checkDice5CheckBox       matlab.ui.control.CheckBox
        checkDice4CheckBox       matlab.ui.control.CheckBox
        checkDice3CheckBox       matlab.ui.control.CheckBox
        checkDice2CheckBox       matlab.ui.control.CheckBox
        checkDice1CheckBox       matlab.ui.control.CheckBox
        HoditButton              matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        dice_value = [0 0 0 0 0]% Description
        round = 1;
        dices;
        checks;
        Number_dice = 5;
    end
    
    methods (Access = private)
        
        function results = Display(app)
            for i = 1 : app.Number_dice
                app.dices(i).Value = app.dice_value(i);
                app.checks(i).Visible = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dices = [ app.Dice1EditField app.Dice2EditField  app.Dice3EditField app.Dice4EditField app.Dice5EditField];
            app.checks = [app.checkDice1CheckBox app.checkDice2CheckBox app.checkDice3CheckBox app.checkDice4CheckBox app.checkDice5CheckBox ];
         %%  app.dice_value = [0 0 0 0 0];
        end

        % Value changed function: HoditButton
        function HoditButtonValueChanged(app, event)
            value = app.HoditButton.Value;           

            if app.round ==1 %%round 1
                app.dice_value = randi(6,5,1);
                app.round =2;
                app.Display();
            elseif app.round ==2
                 for i = 1 : app.Number_dice
                      if app.checks(i).Value
                        app.dice_value(i) = ceil(rand*6);
                        
                     end

                 end
                 app.Display();
                 %%endgame
                 app.round=3;
                 app.EndStatusEditField.Visible= true;

                 app.EndStatusEditField.Value="Prohrál jsi";
                 app.HoditButton.Text="Hrát znovu";

            elseif app.round == 3 %%reset
                app.dice_value = [0 0 0 0 0];
                app.round = 1;
                for i = 1 : app.Number_dice
                       app.dices(i).Value = app.dice_value(i);
                       app.checks(i).Visible = false;
                       app.checks(i).Value = false;
                end
                app.HoditButton.Text="Hodit";
                app.EndStatusEditField.Visible= false;
            end


            %Display


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.HoditButton = uibutton(app.GridLayout, 'state');
            app.HoditButton.ValueChangedFcn = createCallbackFcn(app, @HoditButtonValueChanged, true);
            app.HoditButton.Text = 'Hodit';
            app.checkDice1CheckBox = uicheckbox(app.GridLayout);
            app.checkDice1CheckBox.Visible = 'off';
            app.checkDice1CheckBox.Text = '';
            app.checkDice2CheckBox = uicheckbox(app.GridLayout);
            app.checkDice2CheckBox.Visible = 'off';
            app.checkDice2CheckBox.Text = '';
            app.checkDice3CheckBox = uicheckbox(app.GridLayout);
            app.checkDice3CheckBox.Visible = 'off';
            app.checkDice3CheckBox.Text = '';
            app.checkDice4CheckBox = uicheckbox(app.GridLayout);
            app.checkDice4CheckBox.Visible = 'off';
            app.checkDice4CheckBox.Text = '';
            app.checkDice5CheckBox = uicheckbox(app.GridLayout);
            app.checkDice5CheckBox.Visible = 'off';
            app.checkDice5CheckBox.Text = '';
            app.Dice1EditFieldLabel = uilabel(app.GridLayout);
            app.Dice1EditFieldLabel.Text = 'Dice1';
            app.Dice1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice1EditField.Editable = 'off';
            app.Dice2EditFieldLabel = uilabel(app.GridLayout);
            app.Dice2EditFieldLabel.Text = 'Dice2';
            app.Dice2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice2EditField.Editable = 'off';
            app.Dice3EditFieldLabel = uilabel(app.GridLayout);
            app.Dice3EditFieldLabel.Text = 'Dice3';
            app.Dice3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice3EditField.Editable = 'off';
            app.Dice4EditFieldLabel = uilabel(app.GridLayout);
            app.Dice4EditFieldLabel.Text = 'Dice4';
            app.Dice4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice4EditField.Editable = 'off';
            app.Dice5EditFieldLabel = uilabel(app.GridLayout);
            app.Dice5EditFieldLabel.Text = 'Dice5';
            app.Dice5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Dice5EditField.Editable = 'off';
            app.EndStatusEditFieldLabel = uilabel(app.GridLayout);
            app.EndStatusEditFieldLabel.Visible = 'off';
            app.EndStatusEditFieldLabel.Text = 'EndStatus';
            app.EndStatusEditField = uieditfield(app.GridLayout, 'text');
            app.EndStatusEditField.Editable = 'off';
            app.EndStatusEditField.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kostky_ruta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end